classdef PatientInfoGUI_v4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddPatientInfoUIFigure        matlab.ui.Figure
        INFOfilePanel                 matlab.ui.container.Panel
        LoadfileButton                matlab.ui.control.Button
        SavefileButton                matlab.ui.control.Button
        INFOfileLabel                 matlab.ui.control.Label
        SubjectPanel                  matlab.ui.container.Panel
        SubjectButtonGroup            matlab.ui.container.ButtonGroup
        AddNewSubjectButton           matlab.ui.control.RadioButton
        EditExistingSubjectButton     matlab.ui.control.RadioButton
        IDLabel                       matlab.ui.control.Label
        IDEditField                   matlab.ui.control.EditField
        IDDropDown                    matlab.ui.control.DropDown
        TargetEditFieldLabel          matlab.ui.control.Label
        TargetEditField               matlab.ui.control.EditField
        AddSubjectButton              matlab.ui.control.Button
        AttachDatasheetButton         matlab.ui.control.Button
        FilePanel                     matlab.ui.container.Panel
        FileButtonGroup               matlab.ui.container.ButtonGroup
        AddNewFileButton              matlab.ui.control.RadioButton
        EditExistingFileButton        matlab.ui.control.RadioButton
        FileDropDown                  matlab.ui.control.DropDown
        FileLabel                     matlab.ui.control.Label
        AddFileButton                 matlab.ui.control.Button
        DeleteFileButton              matlab.ui.control.Button
        RecordingPanel                matlab.ui.container.Panel
        HzLabel                       matlab.ui.control.Label
        SamplingrateEditFieldLabel    matlab.ui.control.Label
        SamplingrateEditField         matlab.ui.control.EditField
        ChannelsPanel                 matlab.ui.container.Panel
        ECoGChanLabels                matlab.ui.control.Label
        DBSChanLabels                 matlab.ui.control.Label
        EMGChanLabels                 matlab.ui.control.Label
        MISCChanLabels                matlab.ui.control.Label
        EnterValuesPanel              matlab.ui.container.Panel
        ECoGElectrodesEditFieldLabel  matlab.ui.control.Label
        ECoGElectrodesEditField       matlab.ui.control.EditField
        DBSElectrodesEditFieldLabel   matlab.ui.control.Label
        DBSElectrodesEditField        matlab.ui.control.EditField
        EMGLabelsEditFieldLabel       matlab.ui.control.Label
        EMGLabelsEditField            matlab.ui.control.EditField
        MiscLabelsEditFieldLabel      matlab.ui.control.Label
        MISCLabelsEditField           matlab.ui.control.EditField
        BadcontactsPanel              matlab.ui.container.Panel
        SelectemgbadcontactsButton    matlab.ui.control.Button
        SelectdbsbadcontactsButton    matlab.ui.control.Button
        SelectecogbadcontactsButton   matlab.ui.control.Button
        SelectmiscbadcontactsButton   matlab.ui.control.Button
        VHDRButton                    matlab.ui.control.Button
        ShowvmrktextButton            matlab.ui.control.Button
        SecLabel_3                    matlab.ui.control.Label
        RecordingtimeEditFieldLabel   matlab.ui.control.Label
        RecordingtimeEditField        matlab.ui.control.EditField
        StimulationPanel              matlab.ui.container.Panel
        StimNameLabel                 matlab.ui.control.Label
        StimNameEditField             matlab.ui.control.EditField
        StarttimeEditFieldLabel       matlab.ui.control.Label
        StarttimeEditField            matlab.ui.control.EditField
        StoptimeEditFieldLabel        matlab.ui.control.Label
        StoptimeEditField             matlab.ui.control.EditField
        ChoosestimulationfiletxtordatButton  matlab.ui.control.Button
        SecLabel                      matlab.ui.control.Label
        SecLabel_2                    matlab.ui.control.Label
        ShowstimfiletextButton        matlab.ui.control.Button
        BehaviorPanel                 matlab.ui.container.Panel
        SelectfromvmrkButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        INFOint = []; % Default empty INFO struct
        INFOFile = ''; % Current target for INFO save/load
        currentSubjectIdx = 0; % Which subject are we currently editing/adding
        currentFileIdx = 0; % Which file are we currently editing/adding
        channelData = []; % Electrode -> channel number conversions
        Data = []; % Data for current file
        StimEditFields = {}; % Set of edit fields for stimulation panel
        BehaviorEditFields = {}; % Set of edit fields for behavior panel
        VHDRWindow = []; % Window to display vhdr file text
        VMRKWindow = []; % Window to display vmrk file text
        STIMWindow = []; % Window to display stimulus file text
    end
    
    methods (Access = private)
        
        function setAllComponentsEnabled(app, tf)
            app.TargetEditField.Enable = tf;
            app.TargetEditFieldLabel.Enable = tf;
            %             app.AddexperimentfilevhdrButton.Enable = tf;
            app.SamplingrateEditField.Enable = tf;
            app.SamplingrateEditFieldLabel.Enable = tf;
            app.RecordingtimeEditField.Enable = tf;
            app.RecordingtimeEditFieldLabel.Enable = tf;
            app.VHDRButton.Enable = tf;
            app.ShowvmrktextButton.Enable = tf;
            app.SelectfromvmrkButton.Enable = tf;
            app.HzLabel.Enable = tf;
            app.SecLabel_3.Enable = tf;
            app.ECoGElectrodesEditField.Enable = tf;
            app.ECoGElectrodesEditFieldLabel.Enable = tf;
            app.DBSElectrodesEditField.Enable = tf;
            app.DBSElectrodesEditFieldLabel.Enable = tf;
            app.EMGLabelsEditField.Enable = tf;
            app.EMGLabelsEditFieldLabel.Enable = tf;
            app.MISCLabelsEditField.Enable = tf;
            app.MiscLabelsEditFieldLabel.Enable = tf;
            app.ECoGChanLabels.Enable = tf;
            app.DBSChanLabels.Enable = tf;
            app.EMGChanLabels.Enable = tf;
            app.MISCChanLabels.Enable = tf;
            app.SelectecogbadcontactsButton.Enable = tf;
            app.SelectdbsbadcontactsButton.Enable = tf;
            app.SelectemgbadcontactsButton.Enable = tf;
            app.SelectmiscbadcontactsButton.Enable = tf;
            app.SavefileButton.Enable = tf;
            
            if (tf)
                app.EnterValuesPanel.ForegroundColor = [0,0,0];
                app.ChannelsPanel.ForegroundColor = [0,0,0];
            else
                app.EnterValuesPanel.ForegroundColor = [0.65,0.65,0.65];
                app.ChannelsPanel.ForegroundColor = [0.65,0.65,0.65];
            end
        end
        
        
        function [sr, chans] = parseVHDR(app, file, path)
            text = fileread(fullfile(path, file));
            
            out = regexp(text, 'SamplingInterval=(\d+)', 'tokens');
            sr = round(1/(str2double(out{1}{1})*10^-6));
            
            out = regexp(text, 'Ch(\d+)=(\w+)', 'tokens');
            chans = reshape([out{:}]', 2, length(out))';
        end
        
        function clearFileInfo(app)
            app.currentFileIdx = 0;
            app.FileDropDown.Items = {' '};
            
            clearRecordingSetupPanel(app, false);
            clearStimBehavePanels(app);
            
            app.Data = [];
        end
        
        function updateFileDropDown(app)
            if (app.currentSubjectIdx > 0 && ~isempty(app.INFOint(app.currentSubjectIdx).File))
                app.FileDropDown.Items = [' ', {app.INFOint(app.currentSubjectIdx).File.FileName}];
            else
                app.FileDropDown.Items = {' '};
            end
        end
        
        function clearRecordingSetupPanel(app, enabled)
            
            app.SamplingrateEditField.Value = '';
            app.RecordingtimeEditField.Value = '';
            app.ECoGElectrodesEditField.Value = '';
            app.DBSElectrodesEditField.Value = '';
            app.EMGLabelsEditField.Value = '';
            app.MISCLabelsEditField.Value = '';
            app.ECoGChanLabels.Text = '';
            app.DBSChanLabels.Text = '';
            app.EMGChanLabels.Text = '';
            app.MISCChanLabels.Text = '';
            
            app.SelectecogbadcontactsButton.Text = 'Select bad contacts';
            app.SelectecogbadcontactsButton.FontColor = 'k';
            app.SelectdbsbadcontactsButton.Text = 'Select bad contacts';
            app.SelectdbsbadcontactsButton.FontColor = 'k';
            app.SelectemgbadcontactsButton.Text = 'Select bad contacts';
            app.SelectemgbadcontactsButton.FontColor = 'k';
            app.SelectmiscbadcontactsButton.Text = 'Select bad contacts';
            app.SelectmiscbadcontactsButton.FontColor = 'k';
            
            app.SamplingrateEditField.Enable = enabled;
            app.SamplingrateEditFieldLabel.Enable = enabled;
            app.RecordingtimeEditField.Enable = enabled;
            app.RecordingtimeEditFieldLabel.Enable = enabled;
            app.VHDRButton.Enable = enabled;
            app.ShowvmrktextButton.Enable = enabled;
            app.SelectfromvmrkButton.Enable = enabled;
            app.HzLabel.Enable = enabled;
            app.SecLabel_3.Enable = enabled;
            app.ECoGElectrodesEditField.Enable = enabled;
            app.ECoGElectrodesEditFieldLabel.Enable = enabled;
            app.DBSElectrodesEditField.Enable = enabled;
            app.DBSElectrodesEditFieldLabel.Enable = enabled;
            app.EMGLabelsEditField.Enable = enabled;
            app.EMGLabelsEditFieldLabel.Enable = enabled;
            app.MISCLabelsEditField.Enable = enabled;
            app.MiscLabelsEditFieldLabel.Enable = enabled;
            app.ECoGChanLabels.Enable = enabled;
            app.DBSChanLabels.Enable = enabled;
            app.EMGChanLabels.Enable = enabled;
            app.MISCChanLabels.Enable = enabled;
            
            app.ChoosestimulationfiletxtordatButton.Enable = enabled;
            app.ChoosestimulationfiletxtordatButton.Text = "Choose stimulation file (*.txt or *.dat)";
            app.StimNameEditField.Enable = enabled;
            app.StimNameEditField.Value = "";
            app.StarttimeEditField.Value = "";
            app.StoptimeEditField.Value = "";
            
            if (app.currentFileIdx > 0 && ~isempty(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).ECOGChans))
                app.SelectecogbadcontactsButton.Enable = enabled;
            else
                app.SelectecogbadcontactsButton.Enable = false;
            end
            if (app.currentFileIdx > 0 && ~isempty(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).DBSChans))
                app.SelectdbsbadcontactsButton.Enable = enabled;
            else
                app.SelectdbsbadcontactsButton.Enable = false;
            end
            if (app.currentFileIdx > 0 && ~isempty(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).EMGChans))
                app.SelectemgbadcontactsButton.Enable = enabled;
            else
                app.SelectemgbadcontactsButton.Enable = false;
            end
            if (app.currentFileIdx > 0 && ~isempty(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).MISCChans))
                app.SelectmiscbadcontactsButton.Enable = enabled;
            else
                app.SelectmiscbadcontactsButton.Enable = false;
            end
            
            if (~isempty(app.BehaviorEditFields))
                delete([app.BehaviorEditFields{:}]);
                app.BehaviorEditFields = {};
            end
            
            %             app.StimTable.Data = table("","","","", 'VariableNames', {'Type', 'StartTime', 'StopTime', 'Notes'});
            %             app.BehaviorTable.Data = table("","","","", 'VariableNames', {'Type', 'StartTime', 'StopTime', 'Notes'});
            %             if (enabled)
            %                 app.StimTable.Enable = 'on';
            %                 app.BehaviorTable.Enable = 'on';
            %             else
            %                 app.StimTable.Enable = 'off';
            %                 app.BehaviorTable.Enable = 'off';
            %             end
            
        end
        
        function populateFileInfo(app)
            
            if (app.currentFileIdx > 0)
                file = app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx);
                clearRecordingSetupPanel(app, true);
                app.SamplingrateEditField.Value = num2str(file.SampleRate);
                app.RecordingtimeEditField.Value = num2str(file.TotalRecTime);
                
                if (~isempty(file.ECOGChans))
                    x = sprintf('%d,', file.ECOGChans);
                    app.ECoGChanLabels.Text = x(1:end-1);
                    x = sprintf('%s,', file.ECOGElectrodeIDs{:});
                    app.ECoGElectrodesEditField.Value = x(1:end-1);
                elseif (app.currentFileIdx > 1)
%                     if (~isempty(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx-1).ECOGElectrodeIDs))
%                         x = sprintf('%s,', app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx-1).ECOGElectrodeIDs{:});
%                         app.ECoGElectrodesEditField.Value = x(1:end-1);
%                         ECoGElectrodesEditFieldValueChanged(app, []);
%                     end
                end
                if (~isempty(file.BadECOGContacts))
                    x = sprintf('%d,', file.BadECOGContacts);
                    app.SelectecogbadcontactsButton.Text = x(1:end-1);
                    app.SelectecogbadcontactsButton.FontColor = 'r';
                end
                
                if (~isempty(file.DBSChans))
                    x = sprintf('%d,', file.DBSChans);
                    app.DBSChanLabels.Text = x(1:end-1);
                    x = sprintf('%s,', file.DBSElectrodeIDs{:});
                    app.DBSElectrodesEditField.Value = x(1:end-1);
                elseif (app.currentFileIdx > 1)
%                     if (~isempty(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx-1).DBSElectrodeIDs))
%                         x = sprintf('%s,', app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx-1).DBSElectrodeIDs{:});
%                         app.DBSElectrodesEditField.Value = x(1:end-1);
%                         DBSElectrodesEditFieldValueChanged(app, []);
%                     end
                end
                if (~isempty(file.BadDBSContacts))
                    x = sprintf('%d,', file.BadDBSContacts);
                    app.SelectdbsbadcontactsButton.Text = x(1:end-1);
                    app.SelectdbsbadcontactsButton.FontColor = 'r';
                end
                
                if (~isempty(file.EMGChans))
                    x = sprintf('%d,', file.EMGChans);
                    app.EMGChanLabels.Text = x(1:end-1);
                    x = sprintf('%s,', file.EMGElectrodeIDs{:});
                    app.EMGLabelsEditField.Value = x(1:end-1);
                elseif (app.currentFileIdx > 1)
%                     if (~isempty(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx-1).EMGElectrodeIDs))
%                         x = sprintf('%s,', app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx-1).EMGElectrodeIDs{:});
%                         app.EMGLabelsEditField.Value = x(1:end-1);
%                         EMGLabelsEditFieldValueChanged(app, []);
%                     end
                end
                if (~isempty(file.BadEMGContacts))
                    x = sprintf('%d,', file.BadEMGContacts);
                    app.SelectemgbadcontactsButton.Text = x(1:end-1);
                    app.SelectemgbadcontactsButton.FontColor = 'r';
                end
                
                if (~isempty(file.MISCChans))
                    x = sprintf('%d,', file.MISCChans);
                    app.MISCChanLabels.Text = x(1:end-1);
                    x = sprintf('%s,', file.MISCElectrodeIDs{:});
                    app.MISCLabelsEditField.Value = x(1:end-1);
                elseif (app.currentFileIdx > 1)
%                     if (~isempty(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx-1).MISCElectrodeIDs))
%                         x = sprintf('%s,', app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx-1).MISCElectrodeIDs{:});
%                         app.MISCLabelsEditField.Value = x(1:end-1);
%                         MISCLabelsEditFieldValueChanged(app, []);
%                     end
                end
                if (~isempty(file.BadMISCContacts))
                    x = sprintf('%d,', file.BadMISCContacts);
                    app.SelectmiscbadcontactsButton.Text = x(1:end-1);
                    app.SelectmiscbadcontactsButton.FontColor = 'r';
                end
                
                if (~isempty(file.StimStartTime))
                    app.StarttimeEditField.Value = num2str(file.StimStartTime);
                    app.StoptimeEditField.Value = num2str(file.StimStopTime);
                    app.StarttimeEditField.Enable = true;
                    app.StarttimeEditFieldLabel.Enable = true;
                    app.SecLabel.Enable = true;
                    app.StoptimeEditField.Enable = true;
                    app.StoptimeEditFieldLabel.Enable = true;
                    app.SecLabel_2.Enable = true;
                    
                    app.ChoosestimulationfiletxtordatButton.Enable = true;
                    app.ShowstimfiletextButton.Enable = true;
                    app.StimNameEditField.Enable = true;
                    app.StimNameLabel.Enable = true;
                    
                    if (~isempty(file.StimName))
                        app.StimNameEditField.Value = file.StimName;
                    end
                    
                end
                
                %                 if (~isempty(file.StimFileName))
                %                     app.ChoosestimulationfiletxtordatButton.Enable = true;
                %                     app.ShowstimfiletextButton.Enable = true;
                %                     app.StimNameEditField.Enable = true;
                %                     app.StimNameLabel.Enable = true;
                %
                %                     app.ChoosestimulationfiletxtordatButton.Text = file.StimFileName;
                %                     if (~isempty(file.StimName))
                %                         app.StimNameEditField.Value = file.StimName;
                %                     end
                %                 end
                
                b = app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).Behavior;
                populateBehaviorFields(app, b);
                %                 nrows = size(b,1) - sum(strcmp(b.Type, ""));
                %                 addRemoveEditFieldRow(app, 'Behavior', 'add', [150, 75, 75, 199]);
                %                 for i = 1:nrows
                %                     app.BehaviorEditFields{i, 1}.Value = b.Type(i);
                %                     start = sprintf("%02d:%05.2f", floor(b.StartTime(i)/60), rem(b.StartTime(i),60));
                %                     app.BehaviorEditFields{i, 2}.Value = strrep(start, "NaN:NaN", "");
                %                     stop = sprintf("%02d:%05.2f", floor(b.StopTime(i)/60), rem(b.StopTime(i),60));
                %                     app.BehaviorEditFields{i, 3}.Value = strrep(stop, "NaN:NaN", "");
                %                     app.BehaviorEditFields{i, 4}.Value = b.Notes(i);
                %                     addRemoveEditFieldRow(app, 'Behavior', 'add', [150, 75, 75, 199]);
                %                 end
                
            else
                clearRecordingSetupPanel(app, false);
            end
            
        end
        
        function ECOGLegendHitFcn(app, src, evnt)
            clicked = str2double(evnt.Peer.DisplayName);
            
            file = app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx);
            newbad = setxor(file.BadECOGContacts, clicked);
            if (ismember(clicked, newbad))
                evnt.Peer.LineStyle = '--';
            else
                evnt.Peer.LineStyle = '-';
            end
            
            app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).BadECOGContacts = newbad;
            
            if (~isempty(newbad))
                txt = sprintf('%d,', newbad);
                app.SelectecogbadcontactsButton.Text = txt(1:end-1);
                app.SelectecogbadcontactsButton.FontColor = 'r';
            else
                app.SelectecogbadcontactsButton.Text = 'Select bad contacts';
                app.SelectecogbadcontactsButton.FontColor = 'k';
            end
        end
        
        function DBSLegendHitFcn(app, src, evnt)
            clicked = str2double(evnt.Peer.DisplayName);
            
            file = app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx);
            newbad = setxor(file.BadDBSContacts, clicked);
            if (ismember(clicked, newbad))
                evnt.Peer.LineStyle = '--';
            else
                evnt.Peer.LineStyle = '-';
            end
            
            app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).BadDBSContacts = newbad;
            
            if (~isempty(newbad))
                txt = sprintf('%d,', newbad);
                app.SelectdbsbadcontactsButton.Text = txt(1:end-1);
                app.SelectdbsbadcontactsButton.FontColor = 'r';
            else
                app.SelectdbsbadcontactsButton.Text = 'Select bad contacts';
                app.SelectdbsbadcontactsButton.FontColor = 'k';
            end
        end
        
        function EMGLegendHitFcn(app, src, evnt)
            clicked = str2double(evnt.Peer.DisplayName);
            
            file = app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx);
            newbad = setxor(file.BadEMGContacts, clicked);
            if (ismember(clicked, newbad))
                evnt.Peer.LineStyle = '--';
            else
                evnt.Peer.LineStyle = '-';
            end
            
            app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).BadEMGContacts = newbad;
            
            if (~isempty(newbad))
                txt = sprintf('%d,', newbad);
                app.SelectemgbadcontactsButton.Text = txt(1:end-1);
                app.SelectemgbadcontactsButton.FontColor = 'r';
            else
                app.SelectemgbadcontactsButton.Text = 'Select bad contacts';
                app.SelectemgbadcontactsButton.FontColor = 'k';
            end
        end
        
        function addRemoveEditFieldRow(app, parent, addremove, rowSizes)
            
            if (strcmp(parent, 'Stimulation'))
                field1 = 'StimEditFields';
                field2 = 'StimulationPanel';
            else
                field1 = 'BehaviorEditFields';
                field2 = 'BehaviorPanel';
            end
            
            pheight = app.(field2).InnerPosition(4);%-8;
            
            switch (addremove)
                case 'add'
                    
                    idx = size(app.(field1), 1) + 1;
                    ypos = pheight - (idx)*21;
                    
                    if (ypos < 0) % move all previous rows up
                        ypos = 1;
                        for i = 1:idx-1
                            app.(field1){i,1}.Position(2) = app.(field1){i,1}.Position(2) + 21;
                            app.(field1){i,2}.Position(2) = app.(field1){i,2}.Position(2) + 21;
                            app.(field1){i,3}.Position(2) = app.(field1){i,3}.Position(2) + 21;
                            app.(field1){i,4}.Position(2) = app.(field1){i,4}.Position(2) + 21;
                        end
                    end
                    
                    app.(field1){idx,1} = uieditfield(app.(field2), 'text', 'UserData', {parent, [idx, 1]}, 'ValueChangedFcn', @app.StimBehaveCallback);
                    app.(field1){idx,1}.Position = [8 ypos rowSizes(1) 22];
                    
                    app.(field1){idx,2} = uieditfield(app.(field2), 'text', 'UserData', {parent, [idx, 2]}, 'ValueChangedFcn', @app.StimBehaveCallback);
                    app.(field1){idx,2}.Position = [8+rowSizes(1)-1 ypos rowSizes(2) 22];
                    
                    app.(field1){idx,3} = uieditfield(app.(field2), 'text', 'UserData', {parent, [idx, 3]}, 'ValueChangedFcn', @app.StimBehaveCallback);
                    app.(field1){idx,3}.Position = [8+sum(rowSizes(1:2))-2 ypos rowSizes(3) 22];
                    
                    app.(field1){idx,4} = uieditfield(app.(field2), 'text', 'UserData', {parent, [idx, 4]}, 'ValueChangedFcn', @app.StimBehaveCallback);
                    app.(field1){idx,4}.Position = [8+sum(rowSizes(1:3))-3 ypos rowSizes(4) 22];
                    
                case 'remove'
                    
                    if (size(app.(field1),1) > 0)
                        delete(app.(field1){end,1});
                        delete(app.(field1){end,2});
                        delete(app.(field1){end,3});
                        delete(app.(field1){end,4});
                        app.(field1)(end,:) = [];
                    end
                    
                    if (size(app.(field1),1) > 0)
                        ypos = app.(field1){1,1}.Position(2);
                        if (ypos >= pheight)
                            for i = 1:size(app.(field1),1)
                                app.(field1){i,1}.Position(2) = app.(field1){i,1}.Position(2) - 21;
                                app.(field1){i,2}.Position(2) = app.(field1){i,2}.Position(2) - 21;
                                app.(field1){i,3}.Position(2) = app.(field1){i,3}.Position(2) - 21;
                                app.(field1){i,4}.Position(2) = app.(field1){i,4}.Position(2) - 21;
                            end
                        end
                    end
            end
            
            scroll(app.(field2), 0,0);
            
        end
        
        function StimBehaveCallback(app, src, event)
            parent = src.UserData{1};
            fieldpos = src.UserData{2};
            newval = event.Value;
            
            if (fieldpos(2) == 2 || fieldpos(2) == 3)
                err = false;
                try
                    newData = arrayfun(@str2double, regexp(newval, ':', 'split'));
                catch
                    err = true;
                end
                
                if (err || (any(isnan(newData)) && ~strcmp(newval, '')) || length(newData) > 2)
                    uialert(app.AddPatientInfoUIFigure, 'Input not recognized - please use ''mm:ss'' format', 'Format error');
                    src.Value = '';
                end
                
                if (length(newData) == 2)
                    newData = newData(1)*60 + newData(2);
                end
                
                app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).(parent){fieldpos(1), fieldpos(2)} = newData;
            else
                app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).(parent){fieldpos(1), fieldpos(2)} = string(newval);
            end
            
            % add new row to table if we've filled up all rows:
            tab = app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).(parent);
            filled = all(~strcmp(tab.Type, "")) && all(~isempty(tab.StartTime) & ~isnan(tab.StartTime)) && all(~isempty(tab.StopTime) & ~isnan(tab.StopTime));
            lastrowempty = strcmp(tab.Type(end), "") && (isempty(tab.StartTime(end)) || isnan(tab.StartTime(end))) && (isempty(tab.StopTime(end)) || isnan(tab.StopTime(end)));
            if (filled)
                app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).(parent)(end+1,:) = ...
                    table("",nan,nan,"", 'VariableNames', {'Type', 'StartTime', 'StopTime', 'Notes'});
                addRemoveEditFieldRow(app, parent, 'add', [150, 75, 75, 199]);
            elseif ((fieldpos(1) ~= size(tab, 1)) && lastrowempty)
                app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).(parent)(end,:) = [];
                addRemoveEditFieldRow(app, parent, 'remove', [150, 75, 75, 199]);
            end
            
        end
        
        
        function clearStimBehavePanels(app)
            if (isempty([app.BehaviorEditFields; app.StimEditFields]))
                return;
            end
            
            cellfun(@delete, [app.BehaviorEditFields; app.StimEditFields]);
            app.StimEditFields = [];
            app.BehaviorEditFields = [];
            
            app.ChoosestimulationfiletxtordatButton.Enable = false;
            app.ChoosestimulationfiletxtordatButton.Text = 'Choose stimulation file (*.txt or *.dat)';
            app.ShowstimfiletextButton.Enable = false;
            app.ShowvmrktextButton.Enable = false;
            app.SelectfromvmrkButton.Enable = false;
            app.StimNameEditField.Value = '';
            app.StimNameEditField.Enable = false;
            app.StimNameLabel.Enable = false;
            app.StarttimeEditField.Value = '';
            app.StarttimeEditField.Enable = false;
            app.StarttimeEditFieldLabel.Enable = false;
            app.SecLabel.Enable = false;
            app.StoptimeEditField.Value = '';
            app.StoptimeEditField.Enable = false;
            app.StoptimeEditFieldLabel.Enable = false;
            app.SecLabel_2.Enable = false;
        end
        
        function MISCLegendHitFcn(app, src, evnt)
            clicked = str2double(evnt.Peer.DisplayName);
            
            file = app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx);
            newbad = setxor(file.BadMISCContacts, clicked);
            if (ismember(clicked, newbad))
                evnt.Peer.LineStyle = '--';
            else
                evnt.Peer.LineStyle = '-';
            end
            
            app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).BadMISCContacts = newbad;
            
            if (~isempty(newbad))
                txt = sprintf('%d,', newbad);
                app.SelectmiscbadcontactsButton.Text = txt(1:end-1);
                app.SelectmiscbadcontactsButton.FontColor = 'r';
            else
                app.SelectmiscbadcontactsButton.Text = 'Select bad contacts';
                app.SelectmiscbadcontactsButton.FontColor = 'k';
            end
        end
        
        function [start, stop] = parseVMRK(app, file, path)
            text = fileread(fullfile(path, file));
            
            out = regexp(text, 'Stimulus,[SR]\s+\d+,(\d+)', 'tokens');
            if (~isempty(out))
                out = cellfun(@str2double, out);
                
                start = out(1)/app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).SampleRate;
                stop = out(end)/app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).SampleRate;
            else
                start = [];
                stop = [];
            end
        end
        
        function ButtonPressFigCallback(app, src, event)
            [~, midx] = min(abs(src.XData - event.IntersectionPoint(1)));
            clickedx = src.XData(midx);
            
            if (strcmp(src.UserData(1).SelectedObject.Text, 'Edit Start Times'))
                src.UserData(2).XData = setxor(src.UserData(2).XData, clickedx);
                src.UserData(3).XData = setdiff(src.UserData(3).XData, src.UserData(2).XData);
            else
                src.UserData(3).XData = setxor(src.UserData(3).XData, clickedx);
                src.UserData(2).XData = setdiff(src.UserData(2).XData, src.UserData(3).XData);
            end
            
            src.UserData(2).YData = ones(1,length(src.UserData(2).XData));
            src.UserData(3).YData = ones(1,length(src.UserData(3).XData));
        end
        
        function ButtonPressFigCloseCallback(app, src, event)
            
            starttimes = src.Children.Children(1).Children(2).XData;
            stoptimes = src.Children.Children(1).Children(1).XData;
            
            if (isempty(starttimes) && isempty(stoptimes))
                return;
            end
            
            if (~isempty(app.BehaviorEditFields))
                delete([app.BehaviorEditFields{:}]);
                app.BehaviorEditFields = {};
            end
            
            btable = table('Size', [0, 4], 'VariableTypes', {'string', 'double', 'double', 'string'}, 'VariableNames', {'Type', 'StartTime', 'StopTime', 'Notes'});
            rowidx = 1;
            [t, sortidx] = sort([starttimes, stoptimes]);
            type = [zeros(size(starttimes)), ones(size(stoptimes))]; type = type(sortidx);
            while (~isempty(t))
                
                
                if (type(1) == 0) % start
                    if (length(type) == 1 || type(2) == 0) % another start -> no stop
                        btable(rowidx,:) = {"", t(1), nan, ""};
                        t(1) = [];
                        type(1) = [];
                    else              % stop -> complete row
                        btable(rowidx,:) = {"", t(1), t(2), ""};
                        t(1:2) = [];
                        type(1:2) = [];
                    end
                else              % stop -> no start
                    btable(rowidx,:) = {"", nan, t(1), ""};
                    t(1) = [];
                    type(1) = [];
                end
                rowidx = rowidx + 1;
                
            end
            
            app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).Behavior = btable;
            
            populateBehaviorFields(app, btable);
            
        end
        
        function populateBehaviorFields(app, b)
            
            emptyrows = rowfun(@(a,b,c,d) a=="" && isnan(b) && isnan(c) && d=="", b, 'OutputFormat', 'Uniform');
            
            nrows = size(b,1) - sum(emptyrows);
            addRemoveEditFieldRow(app, 'Behavior', 'add', [150, 75, 75, 199]);
            for i = 1:nrows
                app.BehaviorEditFields{i, 1}.Value = b.Type(i);
                start = sprintf("%02d:%05.2f", floor(b.StartTime(i)/60), rem(b.StartTime(i),60));
                app.BehaviorEditFields{i, 2}.Value = strrep(start, "NaN:NaN", "");
                stop = sprintf("%02d:%05.2f", floor(b.StopTime(i)/60), rem(b.StopTime(i),60));
                app.BehaviorEditFields{i, 3}.Value = strrep(stop, "NaN:NaN", "");
                app.BehaviorEditFields{i, 4}.Value = b.Notes(i);
                addRemoveEditFieldRow(app, 'Behavior', 'add', [150, 75, 75, 199]);
            end
            
        end
        
        function DS_buttoncallback(app, src, event)
            switch (src.Text)
                case 'Add Page'
                    rembutton = src.UserData{1};
                    disppanel = src.UserData{2};
                    ctrlpanel = src.Parent;
                    
                    rembutton.Value = 0;
                    
                    img = screenshot;
                    if (isempty(img))
                        return;
                    end
                    
                    ctrlpanel.UserData{end+1} = img;
                    
                    delete(disppanel.Children);
                    
                    len = length(ctrlpanel.UserData);
                    for i = 1:len
                        bsz = [50*i + 100*(i-1), disppanel.Position(4)/2 - 200/2, 100, 200];
                        uibutton(disppanel, 'push', 'Text', '', 'Icon', ctrlpanel.UserData{i}, 'IconAlignment', 'center', ...
                            'Position', bsz, 'ButtonPushedFcn', @app.DS_buttoncallback, 'UserData', {rembutton, i});
                    end
                    
                case ''
                    rembutton = src.UserData{1};
                    disppanel = rembutton.UserData{2};
                    ctrlpanel = rembutton.Parent;
                    clickedidx = src.UserData{2};
                    
                    if (rembutton.Value == 1) % Remove Page pushed
                        ctrlpanel.UserData(clickedidx) = [];
                        
                        tbd = disppanel.Children;
                        
                        len = length(ctrlpanel.UserData);
                        for i = 1:len
                            bsz = [50*i + 100*(i-1), disppanel.Position(4)/2 - 200/2, 100, 200];
                            uibutton(disppanel, 'push', 'Text', '', 'Icon', ctrlpanel.UserData{i}, 'IconAlignment', 'center', ...
                                'Position', bsz, 'ButtonPushedFcn', @app.DS_buttoncallback, 'UserData', {rembutton, i});
                        end
                        
                        delete(tbd);
                    else
                        figure; imshow(src.Icon);
                    end
                    
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Try to load default INFO file:
            infofile = getenv('INFOFILE');
            if (isempty(infofile))
                return;
            end
            
            app.INFOFile = infofile;
            app.INFOfileLabel.Text = infofile;
            
            load(app.INFOFile, 'INFO');
            app.INFOint = INFO;
            app.SavefileButton.Enable = true;
            
        end

        % Selection changed function: SubjectButtonGroup
        function SubjectButtonGroupSelectionChanged(app, event)
            selectedButton = app.SubjectButtonGroup.SelectedObject;
            
            clearFileInfo(app);
            
            switch (selectedButton.Text)
                case 'Add new'
                    app.IDDropDown.Visible = false;
                    app.IDEditField.Visible = true;
                    app.IDEditField.Value = '';
                    app.TargetEditField.Value = '';
                    app.AddSubjectButton.Visible = true;
                    app.AddSubjectButton.Enable = false;
                    app.AttachDatasheetButton.Enable = false;
                case 'Edit existing'
                    if (~isempty(app.INFOint))
                        app.IDDropDown.Items = [' ', {app.INFOint.ID}];
                    end
                    app.IDDropDown.Visible = true;
                    app.IDDropDown.Value = ' ';
                    app.IDEditField.Visible = false;
                    app.IDEditField.Value = '';
                    app.TargetEditField.Value = '';
                    app.AddSubjectButton.Visible = false;
                    
                    updateFileDropDown(app);
            end
            
            app.TargetEditField.Enable = false;
            app.AddNewFileButton.Enable = false;
            app.EditExistingFileButton.Enable = false;
            app.AddFileButton.Enable = false;
            app.FileLabel.Enable = false;
            app.FileDropDown.Enable = false;
            app.DeleteFileButton.Enable = false;
            
            
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            [file,path] = uigetfile('*.mat', 'Select an INFO file to load', 'MultiSelect', 'off');
            figure(app.AddPatientInfoUIFigure); % give app window focus again - this is a workaround for a bug
            
            if (~isnumeric(file))
                app.INFOFile = fullfile(path, file);
                app.INFOfileLabel.Text = app.INFOFile;
                
                load(app.INFOFile, 'INFO');
                app.INFOint = INFO;
                app.SavefileButton.Enable = true;
            end
        end

        % Button pushed function: SavefileButton
        function SavefileButtonPushed(app, event)
            [file,path] = uiputfile('*.mat', 'Select a file to save current INFO into', app.INFOFile);
            figure(app.AddPatientInfoUIFigure); % give app window focus again - this is a workaround for a bug
            
            if (~isnumeric(file))
                app.INFOFile = fullfile(path, file);
                app.INFOfileLabel.Text = app.INFOFile;
                
                INFO = app.INFOint;
                save(app.INFOFile, 'INFO');
            end
        end

        % Selection changed function: FileButtonGroup
        function FileButtonGroupSelectionChanged(app, event)
            selectedButton = app.FileButtonGroup.SelectedObject;
            
            app.AddFileButton.Text = 'Choose experiment file (*.vhdr)';
            
            clearRecordingSetupPanel(app, false);
            clearStimBehavePanels(app);
            
            app.currentFileIdx = 0;
            app.FileDropDown.Value = ' ';
            
            switch (selectedButton.Text)
                case 'Add new'
                    app.FileDropDown.Visible = false;
                    app.AddFileButton.Visible = true;
                    app.DeleteFileButton.Visible = false;
                    app.DeleteFileButton.Enable = false;
                case 'Edit existing'
                    updateFileDropDown(app);
                    
                    app.FileDropDown.Visible = true;
                    app.AddFileButton.Visible = false;
                    app.DeleteFileButton.Visible = true;
                    app.DeleteFileButton.Enable = false;
            end
            
            
        end

        % Button pushed function: AddSubjectButton
        function AddSubjectButtonPushed(app, event)
            
            if (~isempty(app.INFOint) && any(strcmp({app.INFOint.ID}, app.IDEditField.Value)))
                uialert(app.AddPatientInfoUIFigure, 'ID already exists, please change or use ''Edit existing'' option', 'ID exists');
                return;
            end
            
            numSubj = sum(arrayfun(@(x) ~isempty(x.ID), app.INFOint));
            app.currentSubjectIdx = numSubj + 1;
            
            app.INFOint(app.currentSubjectIdx).ID = app.IDEditField.Value;
            app.INFOint(app.currentSubjectIdx).Target = app.TargetEditField.Value;
            app.INFOint(app.currentSubjectIdx).File = [];
            app.INFOint(app.currentSubjectIdx).DataSheet = [];
            
            app.AddNewFileButton.Enable = true;
            app.EditExistingFileButton.Enable = true;
            app.AddFileButton.Enable = true;
            app.FileLabel.Enable = true;
            app.FileDropDown.Enable = true;
            app.FileDropDown.Value = ' ';
            app.SavefileButton.Enable = true;
            app.AddSubjectButton.Enable = false;
            app.AttachDatasheetButton.Enable = true;
            
        end

        % Value changing function: IDEditField
        function IDEditFieldValueChanging(app, event)
            
            changingValue = event.Value;
            app.TargetEditField.Value = '';
            if (~isempty(changingValue))
                app.TargetEditField.Enable = true;
                app.TargetEditFieldLabel.Enable = true;
            else
                app.TargetEditField.Enable = false;
                app.TargetEditFieldLabel.Enable = false;
            end
            
            app.AddNewFileButton.Enable = false;
            app.EditExistingFileButton.Enable = false;
            app.AddFileButton.Enable = false;
            app.AddFileButton.Text = 'Choose experiment file (*.vhdr)';
            app.FileLabel.Enable = false;
        end

        % Value changing function: TargetEditField
        function TargetEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
            if (strcmp(app.SubjectButtonGroup.SelectedObject.Text, 'Add new'))
                if ~isempty(changingValue) && (app.currentSubjectIdx == 0 || ~strcmp(app.INFOint(app.currentSubjectIdx).ID, app.IDEditField.Value))
                    app.AddSubjectButton.Enable = true;
                elseif (isempty(changingValue))
                    app.AddSubjectButton.Enable = false;
                else
                    app.INFOint(app.currentSubjectIdx).Target = changingValue;
                end
            else
                app.INFOint(app.currentSubjectIdx).Target = changingValue;
            end
            
        end

        % Value changed function: IDDropDown
        function IDDropDownValueChanged(app, event)
            value = app.IDDropDown.Value;
            app.currentSubjectIdx = find(strcmp({app.INFOint.ID}, value));
            clearFileInfo(app);
            if (~isempty(app.currentSubjectIdx))
                app.TargetEditField.Value = app.INFOint(app.currentSubjectIdx).Target;
                
                updateFileDropDown(app);
                
                app.TargetEditField.Enable = true;
                app.AddNewFileButton.Enable = true;
                app.EditExistingFileButton.Enable = true;
                app.AddFileButton.Enable = true;
                app.FileLabel.Enable = true;
                app.FileDropDown.Enable = true;
                app.AttachDatasheetButton.Enable = true;
            else
                app.TargetEditField.Value = '';
                %TODO: clear Edit Existing File dropdown
                
                app.TargetEditField.Enable = false;
                app.AddNewFileButton.Enable = false;
                app.EditExistingFileButton.Enable = false;
                app.AddFileButton.Enable = false;
                app.FileLabel.Enable = false;
                app.FileDropDown.Enable = false;
                app.AttachDatasheetButton.Enable = false;
            end
            
        end

        % Button pushed function: DeleteFileButton
        function DeleteFileButtonPushed(app, event)
            conf = uiconfirm(app.AddPatientInfoUIFigure, 'Are you sure you want to delete this file?', 'Confirm file deletion',...
                'Options', {'OK', 'Cancel'}, 'Icon', 'warning');
            
            if (strcmp(conf, 'OK'))
                app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx) = [];
                app.currentFileIdx = 0;
                app.Data = [];
                app.updateFileDropDown();
            end
        end

        % Button pushed function: AddFileButton
        function AddFileButtonPushed(app, event)
            id = getParticipantID(app.INFOint(app.currentSubjectIdx).ID);
            [file,path] = uigetfile('*.vhdr', sprintf('Select a *.vhdr file to load (ID: %s)', id), 'MultiSelect', 'off');
            figure(app.AddPatientInfoUIFigure); % give app window focus again - this is a workaround for a bug
            
            if (~isnumeric(file))
                
                % test if we've already added this file
                if (~isempty(app.INFOint(app.currentSubjectIdx).File) && any(strcmp({app.INFOint(app.currentSubjectIdx).File.FileName}, file(1:(find(file=='.',1)-1)))))
                    uialert(app.AddPatientInfoUIFigure, 'File already exists for this subject', 'File input error');
                    return;
                end
                
                % get current number of files:
                app.currentFileIdx = sum(arrayfun(@(x) ~isempty(x.SampleRate), app.INFOint(app.currentSubjectIdx).File)) + 1;
                
                if (isempty(app.INFOint(app.currentSubjectIdx).File))
                    app.INFOint(app.currentSubjectIdx).File = struct('FileName', '', ...
                        'ChannelLabels', [], ...
                        'ECOGElectrodeIDs', [], 'ECOGChans', [], 'BadECOGContacts', [], ...
                        'DBSElectrodeIDs', [], 'DBSChans', [], 'BadDBSContacts', [], ...
                        'EMGElectrodeIDs', [], 'EMGChans', [], 'BadEMGContacts', [], ...
                        'MISCElectrodeIDs', [], 'MISCChans', [], 'BadMISCContacts', [], ...
                        'SampleRate', [], 'TotalRecTime', [], ...
                        'StimName', [], 'StimStartTime', [], 'StimStopTime', [], ...
                        'Behavior', table("",nan,nan,"", 'VariableNames', {'Type', 'StartTime', 'StopTime', 'Notes'}));
                else
                    app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx) = struct('FileName', '', ...
                        'ChannelLabels', [], ...
                        'ECOGElectrodeIDs', [], 'ECOGChans', [], 'BadECOGContacts', [], ...
                        'DBSElectrodeIDs', [], 'DBSChans', [], 'BadDBSContacts', [], ...
                        'EMGElectrodeIDs', [], 'EMGChans', [], 'BadEMGContacts', [], ...
                        'MISCElectrodeIDs', [], 'MISCChans', [], 'BadMISCContacts', [], ...
                        'SampleRate', [], 'TotalRecTime', [], ...
                        'StimName', [], 'StimStartTime', [], 'StimStopTime', [], ...
                        'Behavior', table("",nan,nan,"", 'VariableNames', {'Type', 'StartTime', 'StopTime', 'Notes'}));
                end
                
                [~, FileInfo] = LoadDatav2(app.INFOint(app.currentSubjectIdx).ID, file, path, true);
                app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).SampleRate = FileInfo.srate;
                app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).TotalRecTime = FileInfo.xmax;
                app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).ChannelLabels = ...
                    [arrayfun(@(x) num2str(x), 1:FileInfo.nbchan, 'uni', 0)', FileInfo.chanlabels'];
                
                stimevents = find(ismember({FileInfo.event.code}, {'Stimulus', 'Response'}));
                if (~isempty(stimevents))
                    app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).StimStartTime = ...
                        FileInfo.event(stimevents(1)).latency/FileInfo.srate;
                    app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).StimStopTime = ...
                        FileInfo.event(stimevents(end)).latency/FileInfo.srate;
                end
                
                app.StarttimeEditField.Enable = true;
                app.StarttimeEditFieldLabel.Enable = true;
                app.SecLabel.Enable = true;
                app.StoptimeEditField.Enable = true;
                app.StoptimeEditFieldLabel.Enable = true;
                app.SecLabel_2.Enable = true;
                
                if (~isempty(stimevents))
                    app.StarttimeEditField.Value = num2str(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).StimStartTime);
                    app.StoptimeEditField.Value = num2str(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).StimStopTime);
                end
                
                app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).FileName = file(1:(find(file=='.',1)-1));
                
                clearRecordingSetupPanel(app, true);
                populateFileInfo(app);
                app.Data = [];
                app.AddFileButton.Text = file;
            end
            
        end

        % Value changed function: FileDropDown
        function FileDropDownValueChanged(app, event)
            value = app.FileDropDown.Value;
            
            clearStimBehavePanels(app);
            if (strcmp(value, ' '))
                app.currentFileIdx = 0;
                clearRecordingSetupPanel(app, false);
                app.DeleteFileButton.Enable = false;
            else
                app.currentFileIdx = find(strcmp({app.INFOint(app.currentSubjectIdx).File.FileName}, value));
                clearRecordingSetupPanel(app, true);
                populateFileInfo(app);
                app.DeleteFileButton.Enable = true;
            end
            
            app.Data = [];
        end

        % Value changing function: SamplingrateEditField
        function SamplingrateEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).SampleRate = changingValue;
        end

        % Value changed function: ECoGElectrodesEditField
        function ECoGElectrodesEditFieldValueChanged(app, event)
            value = app.ECoGElectrodesEditField.Value;
            
            if (strcmp(value, ''))
                app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).ECOGElectrodeIDs = [];
                app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).ECOGChans = [];
                app.ECoGChanLabels.Text = '';
                app.SelectecogbadcontactsButton.Enable = false;
                return;
            end
            
            electrodeIDs = cellfun(@strtrim, regexp(value, ',', 'split'), 'UniformOutput', false);
            
            ids = cell(size(electrodeIDs));
            channelnums = zeros(size(electrodeIDs));
            for i = 1:length(electrodeIDs)
                if (~isnan(str2double(electrodeIDs{i})))
                    id = ['Ch', electrodeIDs{i}];    % this SHOULD work for the most part but might fail in rare cases...
                    %                     id = ['', electrodeIDs{i}];
                else
                    id = electrodeIDs{i};
                end
                
                if (~isempty(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).ChannelLabels))
                    idx = find(strcmp(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).ChannelLabels(:,2), id), 1);
                else
                    idx = [];
                end
                if (isempty(idx))
                    uialert(app.AddPatientInfoUIFigure, sprintf('Could not find channel name matching ''%s'', please check .vhdr file', id), 'No match');
                    app.ECoGChanLabels.Text = '';
                    app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).ECOGChans = [];
                    app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).ECOGElectrodeIDs = [];
                    return;
                end
                channelnums(i) = str2double(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).ChannelLabels{idx,1});
                ids{i} = id;
            end
            
            x = sprintf('%d,', channelnums);
            app.ECoGChanLabels.Text = x(1:end-1);
            
            app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).ECOGChans = channelnums;
            app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).ECOGElectrodeIDs = ids;
            app.SelectecogbadcontactsButton.Enable = true;
        end

        % Value changed function: DBSElectrodesEditField
        function DBSElectrodesEditFieldValueChanged(app, event)
            value = app.DBSElectrodesEditField.Value;
            
            if (strcmp(value, ''))
                app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).DBSElectrodeIDs = [];
                app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).DBSChans = [];
                app.DBSChanLabels.Text = '';
                app.SelectdbsbadcontactsButton.Enable = false;
                return;
            end
            
            electrodeIDs = cellfun(@strtrim, regexp(value, ',', 'split'), 'UniformOutput', false);
            
            ids = cell(size(electrodeIDs));
            channelnums = zeros(size(electrodeIDs));
            for i = 1:length(electrodeIDs)
                if (~isnan(str2double(electrodeIDs{i})))
                    id = ['Ch', electrodeIDs{i}];    % this SHOULD work for the most part but might fail in rare cases...
                    %                     id = ['', electrodeIDs{i}];
                else
                    id = electrodeIDs{i};
                end
                
                if (~isempty(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).ChannelLabels))
                    idx = find(strcmp(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).ChannelLabels(:,2), id), 1);
                else
                    idx = [];
                end
                
                if (isempty(idx))
                    uialert(app.AddPatientInfoUIFigure, sprintf('Could not find channel name matching ''%s'', please check .vhdr file', id), 'No match');
                    app.DBSChanLabels.Text = '';
                    app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).DBSChans = [];
                    app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).DBSElectrodeIDs = [];
                    return;
                end
                channelnums(i) = str2double(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).ChannelLabels{idx,1});
                ids{i} = id;
            end
            
            x = sprintf('%d,', channelnums);
            app.DBSChanLabels.Text = x(1:end-1);
            
            app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).DBSChans = channelnums;
            app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).DBSElectrodeIDs = ids;
            app.SelectdbsbadcontactsButton.Enable = true;
        end

        % Value changed function: EMGLabelsEditField
        function EMGLabelsEditFieldValueChanged(app, event)
            value = app.EMGLabelsEditField.Value;
            
            if (strcmp(value, ''))
                app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).EMGElectrodeIDs = [];
                app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).EMGChans = [];
                app.EMGChanLabels.Text = '';
                app.SelectemgbadcontactsButton.Enable = false;
                return;
            end
            
            electrodeIDs = cellfun(@strtrim, regexp(value, ',', 'split'), 'UniformOutput', false);
            
            ids = cell(size(electrodeIDs));
            channelnums = zeros(size(electrodeIDs));
            for i = 1:length(electrodeIDs)
                if (~isnan(str2double(electrodeIDs{i})))
                    id = ['Ch', electrodeIDs{i}];    % this SHOULD work for the most part but might fail in rare cases...
                else
                    id = electrodeIDs{i};
                end
                
                if (~isempty(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).ChannelLabels))
                    idx = find(strcmp(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).ChannelLabels(:,2), id), 1);
                else
                    idx = [];
                end
                
                if (isempty(idx))
                    uialert(app.AddPatientInfoUIFigure, sprintf('Could not find channel name matching ''%s'', please check .vhdr file', id), 'No match');
                    app.EMGChanLabels.Text = '';
                    app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).EMGChans = [];
                    app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).EMGElectrodeIDs = [];
                    return;
                end
                channelnums(i) = str2double(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).ChannelLabels{idx,1});
                ids{i} = id;
            end
            
            x = sprintf('%d,', channelnums);
            app.EMGChanLabels.Text = x(1:end-1);
            
            app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).EMGChans = channelnums;
            app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).EMGElectrodeIDs = ids;
            app.SelectemgbadcontactsButton.Enable = true;
        end

        % Button pushed function: SelectecogbadcontactsButton
        function SelectecogbadcontactsButtonPushed(app, event)
            file = app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx);
            if (isempty(file.ECOGChans))
                return;
            end
            if (isempty(app.Data))
                d = uiprogressdlg(app.AddPatientInfoUIFigure, 'Title', 'Loading data, please wait...','Indeterminate','on');
                %                 app.Data = LoadData(file.FileName, file.LocalDir);
                app.Data = LoadDatav2(app.INFOint(app.currentSubjectIdx).ID, file.FileName);
                close(d);
            end
            figure; hold on;
            sr = file.SampleRate;
            t = (1/sr):(1/sr):(size(app.Data,2)/sr);
            x = app.Data(file.ECOGChans,:);
            
            [b, a] = butter(2,[2, 3000]/(sr/2), 'bandpass');
            for i = 1:size(x,1)
                x(i,:) = filtfilt(b, a, x(i,:));
                if (ismember(i, file.BadECOGContacts))
                    style = '--';
                else
                    style = '-';
                end
                plot(t,x(i,:),'LineStyle', style);
            end
            
            %             plot(t,x);
            legend(arrayfun(@num2str, 1:length(file.ECOGChans), 'UniformOutput', false), 'ItemHitFcn', @app.ECOGLegendHitFcn);
        end

        % Button pushed function: SelectdbsbadcontactsButton
        function SelectdbsbadcontactsButtonPushed(app, event)
            file = app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx);
            if (isempty(file.DBSChans))
                return;
            end
            if (isempty(app.Data))
                d = uiprogressdlg(app.AddPatientInfoUIFigure, 'Title', 'Loading data, please wait...','Indeterminate','on');
                %                 app.Data = LoadData(file.FileName, file.LocalDir);
                app.Data = LoadDatav2(app.INFOint(app.currentSubjectIdx).ID, file.FileName);
                close(d);
            end
            figure; hold on;
            sr = file.SampleRate;
            t = (1/sr):(1/sr):(size(app.Data,2)/sr);
            x = app.Data(file.DBSChans,:);
            
            [b, a] = butter(2,[2, 3000]/(sr/2), 'bandpass');
            for i = 1:size(x,1)
                x(i,:) = filtfilt(b, a, x(i,:));
                if (ismember(i, file.BadDBSContacts))
                    style = '--';
                else
                    style = '-';
                end
                plot(t,x(i,:),'LineStyle', style);
            end
            
            %             plot(t,x);
            legend(arrayfun(@num2str, 1:length(file.DBSChans), 'UniformOutput', false), 'ItemHitFcn', @app.DBSLegendHitFcn);
        end

        % Button pushed function: SelectemgbadcontactsButton
        function SelectemgbadcontactsButtonPushed(app, event)
            file = app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx);
            if (isempty(file.EMGChans))
                return;
            end
            if (isempty(app.Data))
                d = uiprogressdlg(app.AddPatientInfoUIFigure, 'Title', 'Loading data, please wait...','Indeterminate','on');
                %                 app.Data = LoadData(file.FileName, file.LocalDir);
                app.Data = LoadDatav2(app.INFOint(app.currentSubjectIdx).ID, file.FileName);
                close(d);
            end
            figure; hold on;
            sr = file.SampleRate;
            t = (1/sr):(1/sr):(size(app.Data,2)/sr);
            x = app.Data(file.EMGChans,:);
            
            [b, a] = butter(2,[10, 1000]/(sr/2), 'bandpass');
            for i = 1:size(x,1)
                x(i,:) = filtfilt(b, a, x(i,:));
                if (ismember(i, file.BadEMGContacts))
                    style = '--';
                else
                    style = '-';
                end
                plot(t,x(i,:),'LineStyle', style);
            end
            
            %             plot(t,x);
            legend(arrayfun(@num2str, 1:length(file.EMGChans), 'UniformOutput', false), 'ItemHitFcn', @app.EMGLegendHitFcn);
        end

        % Callback function
        function StimTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if (indices(2) == 2 || indices(2) == 3)
                err = false;
                try
                    newData = arrayfun(@str2double, regexp(newData, ':', 'split'));
                catch
                    err = true;
                end
                
                if (err || isnan(newData) || length(newData) > 2)
                    uialert(app.AddPatientInfoUIFigure, 'Input not recognized - please use ''mm:ss'' format', 'Format error');
                    return;
                end
                
                if (length(newData) == 2)
                    newData = newData(1)*60 + newData(2);
                end
            end
            
            app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).Stimulation{indices(1), indices(2)} = newData;
        end

        % Button pushed function: VHDRButton
        function VHDRButtonPushed(app, event)
            if (isempty(app.VHDRWindow) || ~isvalid(app.VHDRWindow))
                winpos = app.AddPatientInfoUIFigure.Position;
                app.VHDRWindow = uifigure('Position', [winpos(1)+winpos(3), winpos(2), 700, winpos(4)]);
                uitextarea(app.VHDRWindow, 'Position', [0, 0, 700, winpos(4)], 'Editable', 'off');
            else
                figure(app.VHDRWindow);
            end
            
            %             file = [app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).FileName, '.vhdr'];
            %             path = app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).LocalDir;
            %             app.VHDRWindow.Children.Value = fileread(fullfile(path, file));
            file = app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).FileName;
            [~, FileInfo] = LoadDatav2(app.INFOint(app.currentSubjectIdx).ID, file, [], true);
            app.VHDRWindow.Children.Value = FileInfo2String(FileInfo, 'vhdr');
        end

        % Value changed function: MISCLabelsEditField
        function MISCLabelsEditFieldValueChanged(app, event)
            value = app.MISCLabelsEditField.Value;
            
            if (strcmp(value, ''))
                app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).MISCElectrodeIDs = [];
                app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).MISCChans = [];
                app.MISCChanLabels.Text = '';
                app.SelectmiscbadcontactsButton.Enable = false;
                return;
            end
            
            electrodeIDs = cellfun(@strtrim, regexp(value, ',', 'split'), 'UniformOutput', false);
            
            ids = cell(size(electrodeIDs));
            channelnums = zeros(size(electrodeIDs));
            for i = 1:length(electrodeIDs)
                if (~isnan(str2double(electrodeIDs{i})))
                    id = ['Ch', electrodeIDs{i}];    % this SHOULD work for the most part but might fail in rare cases...
                else
                    id = electrodeIDs{i};
                end
                idx = find(strcmp(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).ChannelLabels(:,2), id), 1);
                if (isempty(idx))
                    uialert(app.AddPatientInfoUIFigure, sprintf('Could not find channel name matching ''%s'', please check .vhdr file', id), 'No match');
                    app.MISCChanLabels.Text = '';
                    app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).MISCChans = [];
                    app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).MISCElectrodeIDs = [];
                    return;
                end
                channelnums(i) = str2double(app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).ChannelLabels{idx,1});
                ids{i} = id;
            end
            
            x = sprintf('%d,', channelnums);
            app.MISCChanLabels.Text = x(1:end-1);
            
            app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).MISCChans = channelnums;
            app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).MISCElectrodeIDs = ids;
            app.SelectmiscbadcontactsButton.Enable = true;
            
        end

        % Button pushed function: SelectmiscbadcontactsButton
        function SelectmiscbadcontactsButtonPushed(app, event)
            file = app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx);
            if (isempty(file.MISCChans))
                return;
            end
            if (isempty(app.Data))
                d = uiprogressdlg(app.AddPatientInfoUIFigure, 'Title', 'Loading data, please wait...','Indeterminate','on');
                %                 app.Data = LoadData(file.FileName, file.LocalDir);
                app.Data = LoadDatav2(app.INFOint(app.currentSubjectIdx).ID, file.FileName);
                close(d);
            end
            figure; hold on;
            sr = file.SampleRate;
            t = (1/sr):(1/sr):(size(app.Data,2)/sr);
            x = app.Data(file.MISCChans,:);
            
            for i = 1:size(x,1)
                %                 x(i,:) = filtfilt(b, a, x(i,:));
                if (ismember(i, file.BadMISCContacts))
                    style = '--';
                else
                    style = '-';
                end
                plot(t,x(i,:),'LineStyle', style);
            end
            
            %             plot(t,x);
            legend(arrayfun(@num2str, 1:length(file.MISCChans), 'UniformOutput', false), 'ItemHitFcn', @app.MISCLegendHitFcn);
        end

        % Button pushed function: 
        % ChoosestimulationfiletxtordatButton
        function ChoosestimulationfiletxtordatButtonPushed(app, event)
            [file,path] = uigetfile({'*.txt;*.dat'}, 'Select a stimulation file to load', 'MultiSelect', 'off');
            figure(app.AddPatientInfoUIFigure); % give app window focus again - this is a workaround for a bug
            
            if (~isnumeric(file))
                %                 app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).StimFileName = file;
                %                 app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).StimFilePath = path;
                
                %%% TODO: extract stim file text and add to .mat data file
                
                app.ChoosestimulationfiletxtordatButton.Text = file;
                
                app.ShowstimfiletextButton.Enable = true;
                app.StimNameEditField.Enable = true;
                app.StimNameLabel.Enable = true;
            end
        end

        % Button pushed function: ShowvmrktextButton
        function ShowvmrktextButtonPushed(app, event)
            if (isempty(app.VMRKWindow) || ~isvalid(app.VMRKWindow))
                winpos = app.AddPatientInfoUIFigure.Position;
                app.VMRKWindow = uifigure('Position', [winpos(1)+winpos(3), winpos(2), 700, winpos(4)]);
                uitextarea(app.VMRKWindow, 'Position', [0, 0, 700, winpos(4)], 'Editable', 'off');
            else
                figure(app.VMRKWindow);
            end
            
            %             file = [app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).FileName, '.vmrk'];
            %             path = app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).LocalDir;
            %             app.VMRKWindow.Children.Value = fileread(fullfile(path, file));
            file = app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).FileName;
            [~, FileInfo] = LoadDatav2(app.INFOint(app.currentSubjectIdx).ID, file, [], true);
            app.VMRKWindow.Children.Value = FileInfo2String(FileInfo, 'vmrk');
        end

        % Button pushed function: ShowstimfiletextButton
        function ShowstimfiletextButtonPushed(app, event)
            if (isempty(app.STIMWindow) || ~isvalid(app.STIMWindow))
                winpos = app.AddPatientInfoUIFigure.Position;
                app.STIMWindow = uifigure('Position', [winpos(1)+winpos(3), winpos(2), 700, winpos(4)]);
                uitextarea(app.STIMWindow, 'Position', [0, 0, 700, winpos(4)], 'Editable', 'off');
            else
                figure(app.STIMWindow);
            end
            
            %             file = app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).StimFileName;
            % %             path = app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).StimFilePath;
            %             app.STIMWindow.Children.Value = fileread(fullfile(path, file));
            
            file = app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).FileName;
            [~, FileInfo] = LoadDatav2(app.INFOint(app.currentSubjectIdx).ID, file, [], true);
            app.STIMWindow.Children.Value = FileInfo2String(FileInfo, 'stim');
        end

        % Value changed function: StarttimeEditField
        function StarttimeEditFieldValueChanged(app, event)
            value = app.StarttimeEditField.Value;
            app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).StimStartTime = str2double(value);
        end

        % Value changed function: StoptimeEditField
        function StoptimeEditFieldValueChanged(app, event)
            value = app.StoptimeEditField.Value;
            app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).StimStopTime = str2double(value);
        end

        % Value changed function: StimNameEditField
        function StimNameEditFieldValueChanged(app, event)
            value = app.StimNameEditField.Value;
            app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).StimName = value;
        end

        % Value changing function: RecordingtimeEditField
        function RecordingtimeEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).TotalRecTime = changingValue;
        end

        % Button pushed function: SelectfromvmrkButton
        function SelectfromvmrkButtonPushed(app, event)
            
            conf = uiconfirm(app.AddPatientInfoUIFigure, 'This will replace any previously defined times', 'Confirm selection',...
                'Options', {'OK', 'Cancel'}, 'Icon', 'warning');
            if (strcmp(conf, 'Cancel'))
                return;
            end
            
            file = app.INFOint(app.currentSubjectIdx).File(app.currentFileIdx).FileName;
            [~, FileInfo] = LoadDatav2(app.INFOint(app.currentSubjectIdx).ID, file, [], true);
            
            btnidxs = strcmp({FileInfo.event.type}, 'BtnPressed');
            btntimes = round(([FileInfo.event(btnidxs).latency]/FileInfo.srate)*10)/10;
            
            if (isempty(btntimes))
                uialert(app.AddPatientInfoUIFigure, 'No button presses found in VMRK file', 'No button presses');
                return;
            end
            
            h = uifigure('Name', 'Select start/stop times', 'Visible', false);
            h.DeleteFcn = @app.ButtonPressFigCloseCallback;
            
            g = uigridlayout(h);
            g.ColumnWidth = {'fit', '1x'};
            g.RowHeight = {'1x', '1x', '1x', '1x', '1x', '1x', '1x', '1x'};
            
            a = uiaxes(g);
            a.Layout.Row = [1,7];
            a.Layout.Column = [1,2];
            a.YTick = [];
            a.YLim = [0, 1.25];
            a.Interactions = [panInteraction('Dimensions', 'x'), zoomInteraction('Dimensions', 'x')];
            
            s = stem(a, btntimes, ones(1,length(btntimes)));
            s.ButtonDownFcn = @app.ButtonPressFigCallback;
            enableDefaultInteractivity(a);
            
            hold(a, 'on');
            l1 = stem(a, 0, 0, 'XData', [], 'YData', [], 'Color', 'g', 'PickableParts', 'none');
            l2 = stem(a, 0, 0, 'XData', [], 'YData', [], 'Color', 'r', 'PickableParts', 'none');
            
            legend(a, 'Button presses', 'Start times', 'Stop times')
            
            bg = uibuttongroup(g);
            bg.Layout.Row = 8;
            bg.Layout.Column = 1;
            
            b1 = uitogglebutton(bg, 'Text', 'Edit Start Times');
            b2 = uitogglebutton(bg, 'Text', 'Edit Stop Times', 'Position', b1.Position + [b1.Position(3)+40,0,0,0]);
            
            s.UserData = [bg, l1, l2];
            
            pause(.5);
            h.Visible = true;
            
        end

        % Button pushed function: AttachDatasheetButton
        function AttachDatasheetButtonPushed(app, event)
            ds = app.INFOint(app.currentSubjectIdx).DataSheet;
            
            h = uifigure('Name', 'Screenshot datasheet pages', 'Visible', false);
            h.UserData = 0;
            h.CloseRequestFcn = @(src, event) set(src, 'UserData', 1);
            
            g = uigridlayout(h);
            g.ColumnWidth = {'1x'};
            g.RowHeight = {'1x', '1x', '1x', '1x', '1x', '1x', '1x', '1x'};
            
            p1 = uipanel(g);
            p1.Layout.Row = [1,7];
            p1.Layout.Column = 1;
            p1.Scrollable = 'on';
            
            p2 = uipanel(g);
            p2.Layout.Row = 8;
            p2.Layout.Column = 1;
            
            g2 = uigridlayout(p2);
            g2.ColumnWidth = {'fit', 'fit'};
            g2.RowHeight = {'1x'};
            
            g2.UserData = ds;
            
            b1 = uibutton(g2, 'push', 'Text', 'Add Page');
            b1.Layout.Row = 1; b1.Layout.Column = 1;
            b2 = uibutton(g2, 'state', 'Text', 'Remove Page');
            b2.Layout.Row = 1; b2.Layout.Column = 2;
            
            b1.ButtonPushedFcn = @app.DS_buttoncallback;
            b1.UserData = {b2, p1};
            b2.ValueChangedFcn = @app.DS_buttoncallback;
            b2.UserData = {b1, p1};
            
            h.Visible = true;
            
            len = length(ds);
            for i = 1:len
                bsz = [50*i + 100*(i-1), p1.Position(4)/2 - 200/2, 100, 200];
                uibutton(p1, 'push', 'Text', '', 'Icon', ds{i}, 'IconAlignment', 'center', ...
                    'Position', bsz, 'ButtonPushedFcn', @app.DS_buttoncallback, 'UserData', {b2, i});
            end
            
            waitfor(h, 'UserData', 1);
            
            app.INFOint(app.currentSubjectIdx).DataSheet = g2.UserData;
            delete(h);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddPatientInfoUIFigure = uifigure('Visible', 'off');
            app.AddPatientInfoUIFigure.Name = 'Add Patient Info';
            app.INFOfilePanel = uipanel(app.AddPatientInfoUIFigure);
            app.INFOfilePanel.Title = 'INFO file';
            app.LoadfileButton = uibutton(app.INFOfilePanel, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.SavefileButton = uibutton(app.INFOfilePanel, 'push');
            app.SavefileButton.ButtonPushedFcn = createCallbackFcn(app, @SavefileButtonPushed, true);
            app.SavefileButton.Enable = 'off';
            app.SavefileButton.Text = 'Save file';
            app.INFOfileLabel = uilabel(app.INFOfilePanel);
            app.INFOfileLabel.Text = '';
            app.SubjectPanel = uipanel(app.AddPatientInfoUIFigure);
            app.SubjectPanel.Title = 'Subject';
            app.SubjectButtonGroup = uibuttongroup(app.SubjectPanel);
            app.SubjectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SubjectButtonGroupSelectionChanged, true);
            app.AddNewSubjectButton = uiradiobutton(app.SubjectButtonGroup);
            app.AddNewSubjectButton.Text = 'Add new';
            app.AddNewSubjectButton.Value = true;
            app.EditExistingSubjectButton = uiradiobutton(app.SubjectButtonGroup);
            app.EditExistingSubjectButton.Text = 'Edit existing';
            app.IDLabel = uilabel(app.SubjectPanel);
            app.IDLabel.Text = 'ID:';
            app.IDEditField = uieditfield(app.SubjectPanel, 'text');
            app.IDEditField.ValueChangingFcn = createCallbackFcn(app, @IDEditFieldValueChanging, true);
            app.IDDropDown = uidropdown(app.SubjectPanel);
            app.IDDropDown.Items = {' '};
            app.IDDropDown.ValueChangedFcn = createCallbackFcn(app, @IDDropDownValueChanged, true);
            app.IDDropDown.Visible = 'off';
            app.IDDropDown.Value = ' ';
            app.TargetEditFieldLabel = uilabel(app.SubjectPanel);
            app.TargetEditFieldLabel.Enable = 'off';
            app.TargetEditFieldLabel.Text = 'Target:';
            app.TargetEditField = uieditfield(app.SubjectPanel, 'text');
            app.TargetEditField.ValueChangingFcn = createCallbackFcn(app, @TargetEditFieldValueChanging, true);
            app.TargetEditField.Enable = 'off';
            app.AddSubjectButton = uibutton(app.SubjectPanel, 'push');
            app.AddSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddSubjectButtonPushed, true);
            app.AddSubjectButton.Enable = 'off';
            app.AddSubjectButton.Text = 'Add';
            app.AttachDatasheetButton = uibutton(app.SubjectPanel, 'push');
            app.AttachDatasheetButton.ButtonPushedFcn = createCallbackFcn(app, @AttachDatasheetButtonPushed, true);
            app.AttachDatasheetButton.Enable = 'off';
            app.AttachDatasheetButton.Text = 'Attach Datasheet';
            app.FilePanel = uipanel(app.AddPatientInfoUIFigure);
            app.FilePanel.Title = 'File';
            app.FileButtonGroup = uibuttongroup(app.FilePanel);
            app.FileButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FileButtonGroupSelectionChanged, true);
            app.AddNewFileButton = uiradiobutton(app.FileButtonGroup);
            app.AddNewFileButton.Enable = 'off';
            app.AddNewFileButton.Text = 'Add new';
            app.AddNewFileButton.Value = true;
            app.EditExistingFileButton = uiradiobutton(app.FileButtonGroup);
            app.EditExistingFileButton.Enable = 'off';
            app.EditExistingFileButton.Text = 'Edit existing';
            app.FileDropDown = uidropdown(app.FilePanel);
            app.FileDropDown.Items = {' '};
            app.FileDropDown.ValueChangedFcn = createCallbackFcn(app, @FileDropDownValueChanged, true);
            app.FileDropDown.Visible = 'off';
            app.FileDropDown.Value = ' ';
            app.FileLabel = uilabel(app.FilePanel);
            app.FileLabel.Enable = 'off';
            app.FileLabel.Text = 'File:';
            app.AddFileButton = uibutton(app.FilePanel, 'push');
            app.AddFileButton.ButtonPushedFcn = createCallbackFcn(app, @AddFileButtonPushed, true);
            app.AddFileButton.Enable = 'off';
            app.AddFileButton.Text = 'Choose experiment file (*.vhdr)';
            app.DeleteFileButton = uibutton(app.FilePanel, 'push');
            app.DeleteFileButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFileButtonPushed, true);
            app.DeleteFileButton.Enable = 'off';
            app.DeleteFileButton.Visible = 'off';
            app.DeleteFileButton.Text = 'Delete';
            app.RecordingPanel = uipanel(app.AddPatientInfoUIFigure);
            app.RecordingPanel.Title = 'Recording';
            app.HzLabel = uilabel(app.RecordingPanel);
            app.HzLabel.Enable = 'off';
            app.HzLabel.Text = 'Hz';
            app.SamplingrateEditFieldLabel = uilabel(app.RecordingPanel);
            app.SamplingrateEditFieldLabel.Enable = 'off';
            app.SamplingrateEditFieldLabel.Text = 'Sampling rate:';
            app.SamplingrateEditField = uieditfield(app.RecordingPanel, 'text');
            app.SamplingrateEditField.ValueChangingFcn = createCallbackFcn(app, @SamplingrateEditFieldValueChanging, true);
            app.SamplingrateEditField.Enable = 'off';
            app.ChannelsPanel = uipanel(app.RecordingPanel);
            app.ChannelsPanel.Title = 'Channels:';
            app.ECoGChanLabels = uilabel(app.ChannelsPanel);
            app.ECoGChanLabels.Enable = 'off';
            app.ECoGChanLabels.Text = '';
            app.DBSChanLabels = uilabel(app.ChannelsPanel);
            app.DBSChanLabels.Enable = 'off';
            app.DBSChanLabels.Text = '';
            app.EMGChanLabels = uilabel(app.ChannelsPanel);
            app.EMGChanLabels.Enable = 'off';
            app.EMGChanLabels.Text = '';
            app.MISCChanLabels = uilabel(app.ChannelsPanel);
            app.MISCChanLabels.Enable = 'off';
            app.MISCChanLabels.Text = '';
            app.EnterValuesPanel = uipanel(app.RecordingPanel);
            app.EnterValuesPanel.Title = 'Enter values separated by commas:';
            app.ECoGElectrodesEditFieldLabel = uilabel(app.EnterValuesPanel);
            app.ECoGElectrodesEditFieldLabel.Enable = 'off';
            app.ECoGElectrodesEditFieldLabel.Text = 'ECoG Electrodes:';
            app.ECoGElectrodesEditField = uieditfield(app.EnterValuesPanel, 'text');
            app.ECoGElectrodesEditField.ValueChangedFcn = createCallbackFcn(app, @ECoGElectrodesEditFieldValueChanged, true);
            app.ECoGElectrodesEditField.Enable = 'off';
            app.DBSElectrodesEditFieldLabel = uilabel(app.EnterValuesPanel);
            app.DBSElectrodesEditFieldLabel.Enable = 'off';
            app.DBSElectrodesEditFieldLabel.Text = 'DBS Electrodes:';
            app.DBSElectrodesEditField = uieditfield(app.EnterValuesPanel, 'text');
            app.DBSElectrodesEditField.ValueChangedFcn = createCallbackFcn(app, @DBSElectrodesEditFieldValueChanged, true);
            app.DBSElectrodesEditField.Enable = 'off';
            app.EMGLabelsEditFieldLabel = uilabel(app.EnterValuesPanel);
            app.EMGLabelsEditFieldLabel.Enable = 'off';
            app.EMGLabelsEditFieldLabel.Text = 'EMG Labels:';
            app.EMGLabelsEditField = uieditfield(app.EnterValuesPanel, 'text');
            app.EMGLabelsEditField.ValueChangedFcn = createCallbackFcn(app, @EMGLabelsEditFieldValueChanged, true);
            app.EMGLabelsEditField.Enable = 'off';
            app.MiscLabelsEditFieldLabel = uilabel(app.EnterValuesPanel);
            app.MiscLabelsEditFieldLabel.Enable = 'off';
            app.MiscLabelsEditFieldLabel.Text = 'Misc. Labels:';
            app.MISCLabelsEditField = uieditfield(app.EnterValuesPanel, 'text');
            app.MISCLabelsEditField.ValueChangedFcn = createCallbackFcn(app, @MISCLabelsEditFieldValueChanged, true);
            app.MISCLabelsEditField.Enable = 'off';
            app.BadcontactsPanel = uipanel(app.RecordingPanel);
            app.BadcontactsPanel.Title = 'Bad contacts:';
            app.SelectemgbadcontactsButton = uibutton(app.BadcontactsPanel, 'push');
            app.SelectemgbadcontactsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectemgbadcontactsButtonPushed, true);
            app.SelectemgbadcontactsButton.Enable = 'off';
            app.SelectemgbadcontactsButton.Text = 'Select bad contacts';
            app.SelectdbsbadcontactsButton = uibutton(app.BadcontactsPanel, 'push');
            app.SelectdbsbadcontactsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdbsbadcontactsButtonPushed, true);
            app.SelectdbsbadcontactsButton.Enable = 'off';
            app.SelectdbsbadcontactsButton.Text = 'Select bad contacts';
            app.SelectecogbadcontactsButton = uibutton(app.BadcontactsPanel, 'push');
            app.SelectecogbadcontactsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectecogbadcontactsButtonPushed, true);
            app.SelectecogbadcontactsButton.Enable = 'off';
            app.SelectecogbadcontactsButton.Text = 'Select bad contacts';
            app.SelectmiscbadcontactsButton = uibutton(app.BadcontactsPanel, 'push');
            app.SelectmiscbadcontactsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmiscbadcontactsButtonPushed, true);
            app.SelectmiscbadcontactsButton.Enable = 'off';
            app.SelectmiscbadcontactsButton.Text = 'Select bad contacts';
            app.VHDRButton = uibutton(app.RecordingPanel, 'push');
            app.VHDRButton.ButtonPushedFcn = createCallbackFcn(app, @VHDRButtonPushed, true);
            app.VHDRButton.Enable = 'off';
            app.VHDRButton.Text = 'Show .vhdr text';
            app.ShowvmrktextButton = uibutton(app.RecordingPanel, 'push');
            app.ShowvmrktextButton.ButtonPushedFcn = createCallbackFcn(app, @ShowvmrktextButtonPushed, true);
            app.ShowvmrktextButton.Enable = 'off';
            app.ShowvmrktextButton.Text = 'Show .vmrk text';
            app.SecLabel_3 = uilabel(app.RecordingPanel);
            app.SecLabel_3.Enable = 'off';
            app.SecLabel_3.Text = 'Sec';
            app.RecordingtimeEditFieldLabel = uilabel(app.RecordingPanel);
            app.RecordingtimeEditFieldLabel.Enable = 'off';
            app.RecordingtimeEditFieldLabel.Text = 'Recording time:';
            app.RecordingtimeEditField = uieditfield(app.RecordingPanel, 'text');
            app.RecordingtimeEditField.ValueChangingFcn = createCallbackFcn(app, @RecordingtimeEditFieldValueChanging, true);
            app.RecordingtimeEditField.Enable = 'off';
            app.StimulationPanel = uipanel(app.AddPatientInfoUIFigure);
            app.StimulationPanel.Title = '  Stimulation';
            app.StimNameLabel = uilabel(app.StimulationPanel);
            app.StimNameLabel.Enable = 'off';
            app.StimNameLabel.Text = 'Stim Name:';
            app.StimNameEditField = uieditfield(app.StimulationPanel, 'text');
            app.StimNameEditField.ValueChangedFcn = createCallbackFcn(app, @StimNameEditFieldValueChanged, true);
            app.StimNameEditField.Enable = 'off';
            app.StarttimeEditFieldLabel = uilabel(app.StimulationPanel);
            app.StarttimeEditFieldLabel.Enable = 'off';
            app.StarttimeEditFieldLabel.Text = 'Start time:';
            app.StarttimeEditField = uieditfield(app.StimulationPanel, 'text');
            app.StarttimeEditField.ValueChangedFcn = createCallbackFcn(app, @StarttimeEditFieldValueChanged, true);
            app.StarttimeEditField.Enable = 'off';
            app.StoptimeEditFieldLabel = uilabel(app.StimulationPanel);
            app.StoptimeEditFieldLabel.Enable = 'off';
            app.StoptimeEditFieldLabel.Text = 'Stop time:';
            app.StoptimeEditField = uieditfield(app.StimulationPanel, 'text');
            app.StoptimeEditField.ValueChangedFcn = createCallbackFcn(app, @StoptimeEditFieldValueChanged, true);
            app.StoptimeEditField.Enable = 'off';
            app.ChoosestimulationfiletxtordatButton = uibutton(app.StimulationPanel, 'push');
            app.ChoosestimulationfiletxtordatButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosestimulationfiletxtordatButtonPushed, true);
            app.ChoosestimulationfiletxtordatButton.Enable = 'off';
            app.ChoosestimulationfiletxtordatButton.Text = 'Choose stimulation file (*.txt or *.dat)';
            app.SecLabel = uilabel(app.StimulationPanel);
            app.SecLabel.Enable = 'off';
            app.SecLabel.Text = 'Sec';
            app.SecLabel_2 = uilabel(app.StimulationPanel);
            app.SecLabel_2.Enable = 'off';
            app.SecLabel_2.Text = 'Sec';
            app.ShowstimfiletextButton = uibutton(app.StimulationPanel, 'push');
            app.ShowstimfiletextButton.ButtonPushedFcn = createCallbackFcn(app, @ShowstimfiletextButtonPushed, true);
            app.ShowstimfiletextButton.Enable = 'off';
            app.ShowstimfiletextButton.Text = 'Show stim file text';
            app.BehaviorPanel = uipanel(app.AddPatientInfoUIFigure);
            app.BehaviorPanel.Title = '  Behavior Type:                     Start time:      Stop time:      Note:';
            app.SelectfromvmrkButton = uibutton(app.AddPatientInfoUIFigure, 'push');
            app.SelectfromvmrkButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfromvmrkButtonPushed, true);
            app.SelectfromvmrkButton.Enable = 'off';
            app.SelectfromvmrkButton.Text = 'Select from vmrk';
            % Show the figure after all components are created
            app.AddPatientInfoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PatientInfoGUI_v4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddPatientInfoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddPatientInfoUIFigure)
        end
    end
end