classdef SpikeSorterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        FileMenu        matlab.ui.container.Menu
        LoadDataMenu    matlab.ui.container.Menu
        GridLayout      matlab.ui.container.GridLayout
        DataPanel       matlab.ui.container.Panel
        ContinuousAxes  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function hfig = CreateSelectDataWindow(app)
            winsize = [250, 200];
            buttonheight = 25;
            buttonwidth = 100;

            vars = evalin('base', 'whos');
            vars = SelectIdx({vars.name}, strcmp({vars.class}, 'double'));

            hfig = uifigure("Name", "Select data", "WindowStyle","modal", 'Visible','off', "Resize", "off");
            hfig.Position(3:4) = winsize;

            hbox = uilistbox(hfig, "Position", [0, buttonheight, winsize(1), winsize(2) - buttonheight]);
            hbox.Items = vars;

            hokbutton = uibutton(hfig, "Text", "OK", "Position", [0, 0, buttonwidth, buttonheight]);
            hokbutton.ButtonPushedFcn = @okbuttonpushed;
            
            hcancelbutton = uibutton(hfig, "Text", "Cancel", "Position", [winsize(1)-buttonwidth, 0, buttonwidth, buttonheight]);
            hcancelbutton.ButtonPushedFcn = @cancelbuttonpushed;

            hfig.Visible = "on";

            function okbuttonpushed(src, event)
                app.UIFigure.UserData.data = evalin('base', hbox.Value);
                delete(hfig);
            end

            function cancelbuttonpushed(src, event)
                delete(hfig);
            end
        end

        function hfig = CreatePreprocessDataWindow(app)
            % Create UIFigure and hide until all components are created
            hfig = uifigure('Visible', 'off');
            hfig.AutoResizeChildren = 'off';
            hfig.Position = [100 100 695 238];
            hfig.Name = 'Preprocess data';
            hfig.Resize = 'off';

            % Create UIAxes
            haxes = uiaxes(hfig);
            xlabel(haxes, 'Time (samples)')
            ylabel(haxes, 'Voltage (au)')
            haxes.FontSize = 10;
            haxes.Position = [1 1 527 238];

            % Create SampleRateHzEditFieldLabel
            SampleRateHzEditFieldLabel = uilabel(hfig);
            SampleRateHzEditFieldLabel.HorizontalAlignment = 'right';
            SampleRateHzEditFieldLabel.FontSize = 10;
            SampleRateHzEditFieldLabel.Position = [548 209 87 22];
            SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz):';

            % Create SampleRateHzEditField
            SampleRateHzEditField = uieditfield(hfig, 'numeric');
            SampleRateHzEditField.FontSize = 10;
            SampleRateHzEditField.Position = [644 209 41 22];
            SampleRateHzEditField.ValueChangedFcn = @SRvaluechanged;

            % Create uVScaleEditFieldLabel
            uVScaleEditFieldLabel = uilabel(hfig);
            uVScaleEditFieldLabel.HorizontalAlignment = 'right';
            uVScaleEditFieldLabel.FontSize = 10;
            uVScaleEditFieldLabel.Position = [587 134 48 22];
            uVScaleEditFieldLabel.Text = 'uV Scale:';

            % Create uVScaleEditField
            uVScaleEditField = uieditfield(hfig, 'numeric');
            uVScaleEditField.FontSize = 10;
            uVScaleEditField.Position = [644 134 41 22];
            uVScaleEditField.Value = 1;
            uVScaleEditField.ValueChangedFcn = @UVvaluechanged;

            % Create LowCutoffHzEditFieldLabel
            LowCutoffHzEditFieldLabel = uilabel(hfig);
            LowCutoffHzEditFieldLabel.HorizontalAlignment = 'right';
            LowCutoffHzEditFieldLabel.FontSize = 10;
            LowCutoffHzEditFieldLabel.Position = [555 97 80 22];
            LowCutoffHzEditFieldLabel.Text = 'Low Cutoff  (Hz):';

            % Create LowCutoffHzEditField
            LowCutoffHzEditField = uieditfield(hfig, 'numeric');
            LowCutoffHzEditField.FontSize = 10;
            LowCutoffHzEditField.Position = [644 97 41 22];
            LowCutoffHzEditField.ValueChangedFcn = @LCvaluechanged;

            % Create HighCutoffHzEditFieldLabel
            HighCutoffHzEditFieldLabel = uilabel(hfig);
            HighCutoffHzEditFieldLabel.HorizontalAlignment = 'right';
            HighCutoffHzEditFieldLabel.FontSize = 10;
            HighCutoffHzEditFieldLabel.Position = [553 60 82 22];
            HighCutoffHzEditFieldLabel.Text = 'High Cutoff  (Hz):';

            % Create HighCutoffHzEditField
            HighCutoffHzEditField = uieditfield(hfig, 'numeric');
            HighCutoffHzEditField.FontSize = 10;
            HighCutoffHzEditField.Position = [644 60 41 22];
            HighCutoffHzEditField.ValueChangedFcn = @HCvaluechanged;

            % Create ResampleRateHzEditFieldLabel
            ResampleRateHzEditFieldLabel = uilabel(hfig);
            ResampleRateHzEditFieldLabel.HorizontalAlignment = 'right';
            ResampleRateHzEditFieldLabel.FontSize = 10;
            ResampleRateHzEditFieldLabel.Position = [537 171 98 22];
            ResampleRateHzEditFieldLabel.Text = 'Resample Rate (Hz):';

            % Create ResampleRateHzEditField
            ResampleRateHzEditField = uieditfield(hfig, 'numeric');
            ResampleRateHzEditField.FontSize = 10;
            ResampleRateHzEditField.Position = [644 171 41 22];
            ResampleRateHzEditField.ValueChangedFcn = @RRvaluechanged;

            % Create DoneButton
            DoneButton = uibutton(hfig, 'push');
            DoneButton.Position = [553 20 100 22];
            DoneButton.Text = 'Done';
            DoneButton.ButtonPushedFcn = @OKbuttonpushed;

            p = plot(haxes, app.UIFigure.UserData.data);
            p.UserData = struct("sr", 0, "rr", 0, "lc", 0, "hc", 0);

            % Show the figure after all components are created
            hfig.Visible = 'on';

            function OKbuttonpushed(src, event)
            end

            function SRvaluechanged(src, event)
                sr = src.Value;

                if (sr == 0)
                    p.XData = 1:length(p.XData);
                    haxes.XLabel.String = 'Time (samples)';
                else
                    p.XData = (1/sr):(1/sr):(length(p.XData)/sr);
                    haxes.XLabel.String = 'Time (sec)';
                end

                p.UserData.sr = sr;
            end

            function UVvaluechanged(src, event)
                scale = src.Value;

                if (scale == 0)
                    scale = 1;
                    haxes.YLabel.String = 'Voltage (au)';
                else
                    haxes.YLabel.String = 'Voltage (uV)';
                end

                haxes.YTick = [haxes.YLim(1), 0, haxes.YLim(2)];
                haxes.YTickLabel = [haxes.YLim(1), 0, haxes.YLim(2)]/scale;
            end

            function LCvaluechanged(src, event)
                lc = src.Value;
                hc = p.UserData.hc;
                sr = p.UserData.sr;

                p.UserData.lc = lc;
                
                if (sr == 0)
                    return;
                end

                if (lc == 0 && hc == 0) % no filter
                    p.YData = app.UIFigure.UserData.data;
                elseif (lc ~= 0 && hc == 0) % high-pass filter
                    [b, a] = butter(2, lc/sr, "high");
                    p.YData = filtfilt(b, a, app.UIFigure.UserData.data);
                elseif (lc == 0 && hc ~= 0) % low-pass filter
                    [b, a] = butter(2, hc/sr, "low");
                    p.YData = filtfilt(b, a, app.UIFigure.UserData.data);
                else % band-pass filter
                    [b, a] = butter(2, [lc, hc]/sr, "bandpass");
                    p.YData = filtfilt(b, a, app.UIFigure.UserData.data);
                end
            end

            function HCvaluechanged(src, event)
                hc = src.Value;
                lc = p.UserData.lc;
                sr = p.UserData.sr;

                p.UserData.hc = hc;
                
                if (sr == 0)
                    return;
                end

                if (lc == 0 && hc == 0) % no filter
                    p.YData = app.UIFigure.UserData.data;
                elseif (lc ~= 0 && hc == 0) % high-pass filter
                    [b, a] = butter(2, lc/sr, "high");
                    p.YData = filtfilt(b, a, app.UIFigure.UserData.data);
                elseif (lc == 0 && hc ~= 0) % low-pass filter
                    [b, a] = butter(2, hc/sr, "low");
                    p.YData = filtfilt(b, a, app.UIFigure.UserData.data);
                else % band-pass filter
                    [b, a] = butter(2, [lc, hc]/sr, "bandpass");
                    p.YData = filtfilt(b, a, app.UIFigure.UserData.data);
                end
            end

            function RRvaluechanged(src, event)
                rr = src.Value;

                sr = p.UserData.sr;

                p.UserData.rr = rr;
                
                if (sr == 0)
                    return;
                end
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function InitApp(app)
            app.UIFigure.UserData = struct("data", []);
        end

        % Menu selected function: LoadDataMenu
        function LoadDataMenuSelected(app, event)
            
            hfig = CreateSelectDataWindow(app);
            
            uiwait(hfig);

            if (isempty(app.UIFigure.UserData.data))
                % clear data from panel?
                return;
            end

            hfig = CreatePreprocessDataWindow(app);
            
            uiwait(hfig);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadDataMenu = uimenu(app.FileMenu);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadDataMenu.Text = 'Load Data';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DataPanel = uipanel(app.GridLayout);
            app.DataPanel.Title = 'Data';
            app.ContinuousAxes = uiaxes(app.DataPanel);
            zlabel(app.ContinuousAxes, 'Z')
            app.ContinuousAxes.YLim = [-1 1];
            app.ContinuousAxes.XTick = [0 0.5 1];
            app.ContinuousAxes.XTickLabel = '';
            app.ContinuousAxes.YTick = [-1 0 1];
            app.ContinuousAxes.YTickLabel = '';
            app.ContinuousAxes.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpikeSorterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @InitApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end